classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AESSCACPAUIFigure    matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        ParametersMenu       matlab.ui.container.Menu
        AboutMenu            matlab.ui.container.Menu
        ExitMenu             matlab.ui.container.Menu
        ClearButton          matlab.ui.control.Button
        CopyKeyButton        matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        StartButton          matlab.ui.control.Button
        desync_EN            matlab.ui.control.CheckBox
        Load_Bin             matlab.ui.control.Button
        Load_Ptxt            matlab.ui.control.Button
        trc_link             matlab.ui.control.EditField
        pln_link             matlab.ui.control.EditField
        TracesLengthLabel    matlab.ui.control.Label
        l_trc                matlab.ui.control.NumericEditField
        StartskippingLabel   matlab.ui.control.Label
        skip_trc             matlab.ui.control.NumericEditField
        NumberofTracesLabel  matlab.ui.control.Label
        n_trc                matlab.ui.control.NumericEditField
        EndskippingLabel     matlab.ui.control.Label
        skip_end_trc         matlab.ui.control.NumericEditField
        Panel_2              matlab.ui.container.Panel
        key1                 matlab.ui.control.EditField
        key2                 matlab.ui.control.EditField
        key3                 matlab.ui.control.EditField
        key4                 matlab.ui.control.EditField
        key5                 matlab.ui.control.EditField
        key6                 matlab.ui.control.EditField
        key7                 matlab.ui.control.EditField
        key8                 matlab.ui.control.EditField
        key9                 matlab.ui.control.EditField
        key10                matlab.ui.control.EditField
        key11                matlab.ui.control.EditField
        key12                matlab.ui.control.EditField
        key13                matlab.ui.control.EditField
        key14                matlab.ui.control.EditField
        key15                matlab.ui.control.EditField
        key16                matlab.ui.control.EditField
        CR1                  matlab.ui.control.NumericEditField
        CR2                  matlab.ui.control.NumericEditField
        CR3                  matlab.ui.control.NumericEditField
        CR4                  matlab.ui.control.NumericEditField
        CR5                  matlab.ui.control.NumericEditField
        CR6                  matlab.ui.control.NumericEditField
        CR7                  matlab.ui.control.NumericEditField
        CR8                  matlab.ui.control.NumericEditField
        CR9                  matlab.ui.control.NumericEditField
        CR10                 matlab.ui.control.NumericEditField
        CR11                 matlab.ui.control.NumericEditField
        CR12                 matlab.ui.control.NumericEditField
        CR13                 matlab.ui.control.NumericEditField
        CR14                 matlab.ui.control.NumericEditField
        CR15                 matlab.ui.control.NumericEditField
        CR16                 matlab.ui.control.NumericEditField
        KeysLabel            matlab.ui.control.Label
        CRLabel              matlab.ui.control.Label
        Panel_3              matlab.ui.container.Panel
        etime                matlab.ui.control.NumericEditField
        ElapsedTimeLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogApp % Dialog box app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LOGOImage = uiimage(app.AESSCACPAUIFigure);
            LOGOImage.Position = [165 393 344 88];
            LOGOImage.ImageSource = '..\Data\afeka2.png';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global f_trc;
            global f_ptxt;
            global gui_hand;
            global elapsedTime;
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Working...';
            
            % Set up the progress bar axis
            app.AESSCACPAUIFigure.AutoResizeChildren = 'off'; % To avoid using the whole uifigure space, advisable to place under the panel
            ax = subplot(1,1,1,'Parent',app.AESSCACPAUIFigure);
            ax.Position=[.1 .4 .8 .05];
            ax.Box='on';
            ax.XTick=[];
            ax.YTick=[];
            ax.Color=[0.9375 0.9375 0.9375];
            ax.XAxis.Limits=[0,1];
            ax.YAxis.Limits=[0,1];
            title(ax,'Progress','Color','w')
            % Create empty patch that will be updated
            global ph;
            global th;
            ph = patch(ax,[0 0 0 0],[0 0 1 1],[0.102 0.623 0]); %greenyellow
            % Create the percent-complete text that will be updated
            th = text(ax,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right','Color','w');
            drawnow;
            
            %updates file links
            f_ptxt = app.pln_link.Value;
            f_trc = app.trc_link.Value;
            %runs main.m
            gui_hand = app();
            main(gui_hand,f_trc,f_ptxt,app.n_trc.Value,app.l_trc.Value,app.skip_trc.Value,app.skip_end_trc.Value,app.desync_EN.Value);
            app.etime.Value = elapsedTime;
            app.StartButton.Text = 'Start';
            app.StartButton.Enable = 'on';
            ph.Visible = 'off';
            th.Visible = 'off';
            ax.Visible = 'off';
        end

        % Button pushed function: Load_Bin
        function Load_BinButtonPushed(app, event)
            [file,path] = uigetfile('*.bin','Select traces *.bin file','..\Data\1.bin');
            app.trc_link.Value = strcat(path,file);
        end

        % Button pushed function: Load_Ptxt
        function Load_PtxtButtonPushed(app, event)
            [file,path] = uigetfile('*.txt','Select Plain text *.txt file','..\Data\in.txt');
            app.pln_link.Value  = strcat(path,file);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.key1.Value='##';
            app.key2.Value='##';
            app.key3.Value='##';
            app.key4.Value='##';
            app.key5.Value='##';
            app.key6.Value='##';
            app.key7.Value='##';
            app.key8.Value='##';
            app.key9.Value='##';
            app.key10.Value='##';
            app.key11.Value='##';
            app.key12.Value='##';
            app.key13.Value='##';
            app.key14.Value='##';
            app.key15.Value='##';
            app.key16.Value='##';
            app.CR1.Value=0;
            app.CR2.Value=0;
            app.CR3.Value=0;
            app.CR4.Value=0;
            app.CR5.Value=0;
            app.CR6.Value=0;
            app.CR7.Value=0;
            app.CR8.Value=0;
            app.CR9.Value=0;
            app.CR10.Value=0;
            app.CR11.Value=0;
            app.CR12.Value=0;
            app.CR13.Value=0;
            app.CR14.Value=0;
            app.CR15.Value=0;
            app.CR16.Value=0;
            app.etime.Value=0;
        end

        % Callback function
        function ButtonPushed(app, event)


% Create processing loop that updates the progress bar

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            closereq;
        end

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected(app, event)
            %winopen('..\Data\info.txt');
            f = figure('menu','none','toolbar','none','Name','Parameters', ...
                'NumberTitle','off','Resize','off');
fid = fopen('..\Data\info.txt');
ph = uipanel(f,'Units','normalized','position',[0.02 0.02 0.96 0.96],'title',...
    'info.txt');
lbh = uicontrol(ph,'style','listbox','Units','normalized','position',...
    [0 0 1 1],'FontSize',9);
indic = 1;
while 1
     tline = fgetl(fid);
     if ~ischar(tline), 
         break
     end
     strings{indic}=tline; 
     indic = indic + 1;
end
fclose(fid);
set(lbh,'string',strings);
set(lbh,'Value',1);
set(lbh,'Selected','on');
        end

        % Button pushed function: CopyKeyButton
        function CopyKeyButtonPushed2(app, event)
            clipboard('copy',strcat(app.key1.Value," ",app.key2.Value," ",app.key3.Value," ",app.key4.Value...
                ," ",app.key5.Value," ",app.key6.Value," ",app.key7.Value," ",app.key8.Value...
                ," ",app.key9.Value," ",app.key10.Value," ",app.key11.Value," ",app.key12.Value...
                ," ",app.key13.Value," ",app.key14.Value," ",app.key15.Value," ",app.key16.Value));
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            % Call dialog box with input values
            uiconfirm(app.AESSCACPAUIFigure,sprintf(['Created by:\nTamir Elazari\n' ...
                'Steven Chich\nMay, 2020']),'About','Options',{'Ok'},'Icon','info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AESSCACPAUIFigure = uifigure('Visible', 'off');
            app.AESSCACPAUIFigure.Name = 'AES SCA CPA';
            app.Menu = uimenu(app.AESSCACPAUIFigure);
            app.Menu.Text = 'Menu';
            app.ParametersMenu = uimenu(app.Menu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected, true);
            app.ParametersMenu.Text = 'Parameters';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ClearButton = uibutton(app.AESSCACPAUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CopyKeyButton = uibutton(app.AESSCACPAUIFigure, 'push');
            app.CopyKeyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyKeyButtonPushed2, true);
            app.CopyKeyButton.Text = 'Copy Key';
            app.Panel = uipanel(app.AESSCACPAUIFigure);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.desync_EN = uicheckbox(app.Panel);
            app.desync_EN.Text = 'desync_EN';
            app.Load_Bin = uibutton(app.Panel, 'push');
            app.Load_Bin.ButtonPushedFcn = createCallbackFcn(app, @Load_BinButtonPushed, true);
            app.Load_Bin.Text = 'Load traces';
            app.Load_Ptxt = uibutton(app.Panel, 'push');
            app.Load_Ptxt.ButtonPushedFcn = createCallbackFcn(app, @Load_PtxtButtonPushed, true);
            app.Load_Ptxt.Text = 'Load Plain Input';
            app.trc_link = uieditfield(app.Panel, 'text');
            app.trc_link.Editable = 'off';
            app.trc_link.Value = '..\Data\1.bin';
            app.pln_link = uieditfield(app.Panel, 'text');
            app.pln_link.Editable = 'off';
            app.pln_link.Value = '..\Data\in.txt';
            app.TracesLengthLabel = uilabel(app.Panel);
            app.TracesLengthLabel.Text = 'Traces Length';
            app.l_trc = uieditfield(app.Panel, 'numeric');
            app.l_trc.ValueDisplayFormat = '%.0f';
            app.l_trc.Value = 370000;
            app.StartskippingLabel = uilabel(app.Panel);
            app.StartskippingLabel.Text = 'Start skipping';
            app.skip_trc = uieditfield(app.Panel, 'numeric');
            app.skip_trc.ValueDisplayFormat = '%.0f';
            app.skip_trc.Value = 46500;
            app.NumberofTracesLabel = uilabel(app.Panel);
            app.NumberofTracesLabel.Text = 'Number of Traces';
            app.n_trc = uieditfield(app.Panel, 'numeric');
            app.n_trc.ValueDisplayFormat = '%.0f';
            app.n_trc.Value = 200;
            app.EndskippingLabel = uilabel(app.Panel);
            app.EndskippingLabel.Text = 'End skipping';
            app.skip_end_trc = uieditfield(app.Panel, 'numeric');
            app.skip_end_trc.ValueDisplayFormat = '%.0f';
            app.skip_end_trc.Value = 298500;
            app.Panel_2 = uipanel(app.AESSCACPAUIFigure);
            app.key1 = uieditfield(app.Panel_2, 'text');
            app.key1.Editable = 'off';
            app.key1.Value = '##';
            app.key2 = uieditfield(app.Panel_2, 'text');
            app.key2.Editable = 'off';
            app.key2.Value = '##';
            app.key3 = uieditfield(app.Panel_2, 'text');
            app.key3.Editable = 'off';
            app.key3.Value = '##';
            app.key4 = uieditfield(app.Panel_2, 'text');
            app.key4.Editable = 'off';
            app.key4.Value = '##';
            app.key5 = uieditfield(app.Panel_2, 'text');
            app.key5.Editable = 'off';
            app.key5.Value = '##';
            app.key6 = uieditfield(app.Panel_2, 'text');
            app.key6.Editable = 'off';
            app.key6.Value = '##';
            app.key7 = uieditfield(app.Panel_2, 'text');
            app.key7.Editable = 'off';
            app.key7.Value = '##';
            app.key8 = uieditfield(app.Panel_2, 'text');
            app.key8.Editable = 'off';
            app.key8.Value = '##';
            app.key9 = uieditfield(app.Panel_2, 'text');
            app.key9.Editable = 'off';
            app.key9.Value = '##';
            app.key10 = uieditfield(app.Panel_2, 'text');
            app.key10.Editable = 'off';
            app.key10.Value = '##';
            app.key11 = uieditfield(app.Panel_2, 'text');
            app.key11.Editable = 'off';
            app.key11.Value = '##';
            app.key12 = uieditfield(app.Panel_2, 'text');
            app.key12.Editable = 'off';
            app.key12.Value = '##';
            app.key13 = uieditfield(app.Panel_2, 'text');
            app.key13.Editable = 'off';
            app.key13.Value = '##';
            app.key14 = uieditfield(app.Panel_2, 'text');
            app.key14.Editable = 'off';
            app.key14.Value = '##';
            app.key15 = uieditfield(app.Panel_2, 'text');
            app.key15.Editable = 'off';
            app.key15.Value = '##';
            app.key16 = uieditfield(app.Panel_2, 'text');
            app.key16.Editable = 'off';
            app.key16.Value = '##';
            app.CR1 = uieditfield(app.Panel_2, 'numeric');
            app.CR1.ValueDisplayFormat = '%.3f';
            app.CR1.Editable = 'off';
            app.CR2 = uieditfield(app.Panel_2, 'numeric');
            app.CR2.ValueDisplayFormat = '%.3f';
            app.CR2.Editable = 'off';
            app.CR3 = uieditfield(app.Panel_2, 'numeric');
            app.CR3.ValueDisplayFormat = '%.3f';
            app.CR3.Editable = 'off';
            app.CR4 = uieditfield(app.Panel_2, 'numeric');
            app.CR4.ValueDisplayFormat = '%.3f';
            app.CR4.Editable = 'off';
            app.CR5 = uieditfield(app.Panel_2, 'numeric');
            app.CR5.ValueDisplayFormat = '%.3f';
            app.CR5.Editable = 'off';
            app.CR6 = uieditfield(app.Panel_2, 'numeric');
            app.CR6.ValueDisplayFormat = '%.3f';
            app.CR6.Editable = 'off';
            app.CR7 = uieditfield(app.Panel_2, 'numeric');
            app.CR7.ValueDisplayFormat = '%.3f';
            app.CR7.Editable = 'off';
            app.CR8 = uieditfield(app.Panel_2, 'numeric');
            app.CR8.ValueDisplayFormat = '%.3f';
            app.CR8.Editable = 'off';
            app.CR9 = uieditfield(app.Panel_2, 'numeric');
            app.CR9.ValueDisplayFormat = '%.3f';
            app.CR9.Editable = 'off';
            app.CR10 = uieditfield(app.Panel_2, 'numeric');
            app.CR10.ValueDisplayFormat = '%.3f';
            app.CR10.Editable = 'off';
            app.CR11 = uieditfield(app.Panel_2, 'numeric');
            app.CR11.ValueDisplayFormat = '%.3f';
            app.CR11.Editable = 'off';
            app.CR12 = uieditfield(app.Panel_2, 'numeric');
            app.CR12.ValueDisplayFormat = '%.3f';
            app.CR12.Editable = 'off';
            app.CR13 = uieditfield(app.Panel_2, 'numeric');
            app.CR13.ValueDisplayFormat = '%.3f';
            app.CR13.Editable = 'off';
            app.CR14 = uieditfield(app.Panel_2, 'numeric');
            app.CR14.ValueDisplayFormat = '%.3f';
            app.CR14.Editable = 'off';
            app.CR15 = uieditfield(app.Panel_2, 'numeric');
            app.CR15.ValueDisplayFormat = '%.3f';
            app.CR15.Editable = 'off';
            app.CR16 = uieditfield(app.Panel_2, 'numeric');
            app.CR16.ValueDisplayFormat = '%.3f';
            app.CR16.Editable = 'off';
            app.KeysLabel = uilabel(app.Panel_2);
            app.KeysLabel.Text = 'Keys:';
            app.CRLabel = uilabel(app.Panel_2);
            app.CRLabel.Text = 'CR:';
            app.Panel_3 = uipanel(app.AESSCACPAUIFigure);
            app.etime = uieditfield(app.Panel_3, 'numeric');
            app.etime.ValueDisplayFormat = '%.3f';
            app.etime.Editable = 'off';
            app.ElapsedTimeLabel = uilabel(app.Panel_3);
            app.ElapsedTimeLabel.Text = 'Elapsed Time';
            % Show the figure after all components are created
            app.AESSCACPAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AESSCACPAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AESSCACPAUIFigure)
        end
    end
end